classdef RL_Multivariable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UITable                        matlab.ui.control.Table
        RegresinLinealMultivariableLabel  matlab.ui.control.Label
        BuscararchivoButton            matlab.ui.control.Button
        FuncingeneradaLabel            matlab.ui.control.Label
        RealizarregresinlinealButton   matlab.ui.control.Button
        PrediccindelaestanciaLabel     matlab.ui.control.Label
        PuntajeGREEditFieldLabel       matlab.ui.control.Label
        PuntajeGREEditField            matlab.ui.control.NumericEditField
        PuntajeTOEFLEditFieldLabel     matlab.ui.control.Label
        PuntajeTOEFLEditField          matlab.ui.control.NumericEditField
        PromedioGPAEditFieldLabel      matlab.ui.control.Label
        PromedioGPAEditField           matlab.ui.control.NumericEditField
        PrediccinButton                matlab.ui.control.Button
        X1PuntajeGRELabel              matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        X1Label                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        X2Label                        matlab.ui.control.Label
        X3Label                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        X2PuntajeTOEFLLabel            matlab.ui.control.Label
        X3PromedioGPALabel             matlab.ui.control.Label
        YPosibilidaddeadmisinLabel     matlab.ui.control.Label
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        ResultadoPrediccion            matlab.ui.control.NumericEditField
        ParaobtenertusresultadosLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuscararchivoButton
        function BuscararchivoButtonPushed(app, event)
            [nombre, ruta] = uigetfile('*.csv','Abrir un archivo de datos');
            if nombre == 0
                return;
            else
                datos = strcat(ruta, nombre);
                app.UITable.Data = readtable(datos);
            end
            
            app.UITable.Data(:,1) = [];
            
            for i = 1:3
                app.UITable.Data(:,3) = [];
            end
            
            app.UITable.Data(:,4) = [];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            
        end

        % Button pushed function: RealizarregresinlinealButton
        function RealizarregresinlinealButtonPushed(app, event)
           
            data_matrix = app.UITable.Data{:,:};
            X = data_matrix;
            Y = data_matrix(:,4);


            X(:,4) = X(:,3);
            X(:,3) = X(:,2);
            X(:,2) = X(:,1);
            
            X(:,1) = 1;
            
            
            T = X.';
            
            A = T * X;
            
            B = inv(A);
            
            
            C = T * Y;
            
            D = B * C;
            
            B0 = D(1);
            B1 = D(2);
            B2 = D(3);
            B3 = D(4);
            
            app.YEditField.Value = B0;
            app.EditField.Value = B1;
            app.EditField_2.Value = B2;
            app.EditField_3.Value = B3;
        end

        % Button pushed function: PrediccinButton
        function PrediccinButtonPushed(app, event)
            gre =  app.PuntajeGREEditField.Value;
            toefle = app.PuntajeTOEFLEditField.Value;
            gpae = app.PromedioGPAEditField.Value;
            b0 = app.YEditField.Value;
            b1 = app.EditField.Value;
            b2 = app.EditField_2.Value;
            b3 = app.EditField_3.Value;
            
            prediccion = b0 + b1*gre +  b2*toefle + b3*gpae;
            
            if prediccion <= 0
                app.ResultadoPrediccion.Value = prediccion;
                app.Label_4.Text = 'Debes esforzarte mas ¬¬';
            else
                app.ResultadoPrediccion.Value = prediccion;
                app.Label_4.Text = 'Super, Sigue asi!';
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Puntaje GRE'; 'Puntaje TOEFL'; 'GPA Pregrado'; 'Probabilidad de admisión'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RegresinLinealMultivariableLabel = uilabel(app.UIFigure);
            app.RegresinLinealMultivariableLabel.Text = 'Regresión Lineal Multivariable';
            app.BuscararchivoButton = uibutton(app.UIFigure, 'push');
            app.BuscararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscararchivoButtonPushed, true);
            app.BuscararchivoButton.Text = 'Buscar archivo';
            app.FuncingeneradaLabel = uilabel(app.UIFigure);
            app.FuncingeneradaLabel.Text = {'Función generada: '; ''};
            app.RealizarregresinlinealButton = uibutton(app.UIFigure, 'push');
            app.RealizarregresinlinealButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarregresinlinealButtonPushed, true);
            app.RealizarregresinlinealButton.Text = {'Realizar regresión lineal'; ''};
            app.PrediccindelaestanciaLabel = uilabel(app.UIFigure);
            app.PrediccindelaestanciaLabel.Text = {'Predicción de la estancia'; ''};
            app.PuntajeGREEditFieldLabel = uilabel(app.UIFigure);
            app.PuntajeGREEditFieldLabel.Text = 'Puntaje GRE';
            app.PuntajeGREEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuntajeTOEFLEditFieldLabel = uilabel(app.UIFigure);
            app.PuntajeTOEFLEditFieldLabel.Text = 'Puntaje TOEFL';
            app.PuntajeTOEFLEditField = uieditfield(app.UIFigure, 'numeric');
            app.PromedioGPAEditFieldLabel = uilabel(app.UIFigure);
            app.PromedioGPAEditFieldLabel.Text = 'Promedio GPA';
            app.PromedioGPAEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrediccinButton = uibutton(app.UIFigure, 'push');
            app.PrediccinButton.ButtonPushedFcn = createCallbackFcn(app, @PrediccinButtonPushed, true);
            app.PrediccinButton.Text = {'Predicción'; ''};
            app.X1PuntajeGRELabel = uilabel(app.UIFigure);
            app.X1PuntajeGRELabel.Text = 'X1 = Puntaje GRE';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = '*X1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '+';
            app.X2Label = uilabel(app.UIFigure);
            app.X2Label.Text = {'*X2'; ''};
            app.X3Label = uilabel(app.UIFigure);
            app.X3Label.Text = '*X3';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '+';
            app.X2PuntajeTOEFLLabel = uilabel(app.UIFigure);
            app.X2PuntajeTOEFLLabel.Text = {'X2 = Puntaje TOEFL'; ''};
            app.X3PromedioGPALabel = uilabel(app.UIFigure);
            app.X3PromedioGPALabel.Text = 'X3 = Promedio GPA';
            app.YPosibilidaddeadmisinLabel = uilabel(app.UIFigure);
            app.YPosibilidaddeadmisinLabel.Text = 'Y = Posibilidad de admisión';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y =';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.ResultadoPrediccion = uieditfield(app.UIFigure, 'numeric');
            app.ResultadoPrediccion.Editable = 'off';
            app.ParaobtenertusresultadosLabel = uilabel(app.UIFigure);
            app.ParaobtenertusresultadosLabel.Text = '* Para obtener tus resultados no olvides subir tu archivo y realizar la regresion antes de introducir tus datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RL_Multivariable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end