classdef RL_Simple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        BuscararchivoButton        matlab.ui.control.Button
        PredecirButton             matlab.ui.control.Button
        CalculalaposibilidaddeadmisinLabel  matlab.ui.control.Label
        PuntajeGREEditFieldLabel   matlab.ui.control.Label
        PuntajeGREEditField        matlab.ui.control.NumericEditField
        ResultadoLabel             matlab.ui.control.Label
        RegresinlinealsimpleLabel  matlab.ui.control.Label
        RealizarregresinlinealygrficaButton  matlab.ui.control.Button
        resultadoPrediccion        matlab.ui.control.NumericEditField
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuscararchivoButton
        function BuscararchivoButtonPushed(app, event)
            [nombre, ruta] = uigetfile('*.csv','Abrir un archivo de datos');
            if nombre == 0
                return;
            else
                datos = strcat(ruta, nombre);
                app.UITable.Data = readtable(datos);
            end
            
            app.UITable.Data(:,1) = [];
            for i = 1:6
                 app.UITable.Data(:,2) = [];
            end
             
             
        end

        % Button pushed function: 
        % RealizarregresinlinealygrficaButton
        function RealizarregresinlinealygrficaButtonPushed(app, event)
            %Regresión lineal por mínimos cuadrados.
            
            data_matrix = app.UITable.Data{:,:};
            scatter(data_matrix(:,1),data_matrix(:,2));
            
            plot(data_matrix(:,1),data_matrix(:,2),'*');xlabel('Puntaje GRE'),ylabel('Posibilidad de admisión'),
            title('Regresión lineal simple');
            grid on;
            hold on;
            
            n = length(data_matrix(:,1));
            sumxi_yi = sum(data_matrix(:,1).*data_matrix(:,2));
            sumxi = sum(data_matrix(:,1));
            sumxi_cuadrado = sum(data_matrix(:,1).^2);
            cuadrado_sumxi = (sumxi)^2;
            sum_yi= sum(data_matrix(:,2));
            
            a1 = ((n*sumxi_yi) - (sumxi*sum_yi)) / (n*sumxi_cuadrado-cuadrado_sumxi);
            
            x_testada = mean(data_matrix(:,1));
            y_testada = mean(data_matrix(:,2));
            a0 = y_testada - a1 * x_testada;
            linea_ajuste = a0 + a1 * data_matrix(:,1);  
            plot(data_matrix(:,1),linea_ajuste,'r--');
            legend('Conjunto de datos', 'Linea de ajuste');
        end

        % Value changed function: PuntajeGREEditField
        function PuntajeGREEditFieldValueChanged(app, event)
            value = app.PuntajeGREEditField.Value;
            
        end

        % Button pushed function: PredecirButton
        function PredecirButtonPushed(app, event)
            data_matrix = app.UITable.Data{:,:};
            
            n = length(data_matrix(:,1));
            sumxi_yi = sum(data_matrix(:,1).*data_matrix(:,2));
            sumxi = sum(data_matrix(:,1));
            sumxi_cuadrado = sum(data_matrix(:,1).^2);
            cuadrado_sumxi = (sumxi)^2;
            sum_yi= sum(data_matrix(:,2));
            
            a1 = ((n*sumxi_yi) - (sumxi*sum_yi)) / (n*sumxi_cuadrado-cuadrado_sumxi);
            
            x_testada = mean(data_matrix(:,1));
            y_testada = mean(data_matrix(:,2));
            a0 = y_testada - a1 * x_testada;
            
            x = app.PuntajeGREEditField.Value;
            prediccion= a0 + a1*x;
            app.resultadoPrediccion.Value = prediccion;
            
            
        end

        % Value changed function: resultadoPrediccion
        function resultadoPrediccionValueChanged(app, event)
            value = app.resultadoPrediccion.Value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Puntaje GRE'; 'Posibilidad de admisión'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BuscararchivoButton = uibutton(app.UIFigure, 'push');
            app.BuscararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscararchivoButtonPushed, true);
            app.BuscararchivoButton.Text = 'Buscar archivo';
            app.PredecirButton = uibutton(app.UIFigure, 'push');
            app.PredecirButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirButtonPushed, true);
            app.PredecirButton.Text = 'Predecir';
            app.CalculalaposibilidaddeadmisinLabel = uilabel(app.UIFigure);
            app.CalculalaposibilidaddeadmisinLabel.Text = 'Calcula la posibilidad de admisión';
            app.PuntajeGREEditFieldLabel = uilabel(app.UIFigure);
            app.PuntajeGREEditFieldLabel.Text = 'Puntaje GRE:';
            app.PuntajeGREEditField = uieditfield(app.UIFigure, 'numeric');
            app.PuntajeGREEditField.ValueChangedFcn = createCallbackFcn(app, @PuntajeGREEditFieldValueChanged, true);
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado:';
            app.RegresinlinealsimpleLabel = uilabel(app.UIFigure);
            app.RegresinlinealsimpleLabel.Text = 'Regresión lineal simple';
            app.RealizarregresinlinealygrficaButton = uibutton(app.UIFigure, 'push');
            app.RealizarregresinlinealygrficaButton.ButtonPushedFcn = createCallbackFcn(app, @RealizarregresinlinealygrficaButtonPushed, true);
            app.RealizarregresinlinealygrficaButton.Text = {'Realizar regresión lineal y gráfica'; ''};
            app.resultadoPrediccion = uieditfield(app.UIFigure, 'numeric');
            app.resultadoPrediccion.ValueChangedFcn = createCallbackFcn(app, @resultadoPrediccionValueChanged, true);
            app.resultadoPrediccion.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RL_Simple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end